classdef visualizeResults < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        PleasewaitLabel         matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        UITable_2               matlab.ui.control.Table
        FinalEmbryoSizeLabel    matlab.ui.control.Label
        AverageGrowthRateLabel  matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        Button_2                matlab.ui.control.Button
        UITable2                matlab.ui.control.Table
        Panel                   matlab.ui.container.Panel
        SaveresultsButton       matlab.ui.control.Button
        LoadnewvideosButton     matlab.ui.control.Button
        SaveImagesLabelsButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        file % List of files [file file file]
        finalResult % Output information
        storePixels % {(empty) (video pixel array) (video pixel array) ...}
        storeAGRRank % Stores the average growth rate ranks for table [name, rank; name rank;]
        storeFinalSizeRank % Stores the final size ranking for the table [name, rank; name rank;]
        storeRegress
        storeAll
        storeGraph
        storeGraph2
        current
        storeTime
        saveImage
    end
    
    methods (Access = private)
        
        function output(app)
            mkdir('Images & Labels');
            cd('Images & Labels');
            output = ["Videos","Time (h)","Initial Size (um^2)","Final Size (um^2)","Final Size Rank","Average Growth Rate","Avg Growth Rate Rank"];
            imageSize = 496;
            pixel2real = 0.58*0.58;
            load('net_all_combined_all.mat', 'net');
            fileSize = size(app.file);
            numVideos = fileSize(2);
            maxFrames = 0;
            rankGrowth = zeros(numVideos, 2);
            rankSize = zeros(numVideos, 2);
            for i=1:numVideos
                rankGrowth(i) = i;
                rankSize(i) = i;
            end
            for i=1:numVideos
                files = app.file(i);
                [filepath,name,~] = fileparts(files);
                mkdir(name);
                mkdir(strcat(name, '_new'));
                vidObj = VideoReader(files);
                time_to_remember = vidObj.CurrentTime;
                numFrames = 0;
                while hasFrame(vidObj)
                    readFrame(vidObj);
                    numFrames = numFrames + 1;
                end
                if numFrames > maxFrames
                    maxFrames = numFrames;
                end
                pixelArray = zeros(1,numFrames);
                timeArray = zeros(1,numFrames);
                vidObj.CurrentTime = time_to_remember;
                for k=1:numFrames
                    Image = readFrame(vidObj);
                    Image = rgb2gray(Image);
                    Image = imresize(Image, [imageSize imageSize]);
                    timeArray(k) = timeCalc(Image);
                    if(k<10)
                        filename=strcat(name,'/','00',num2str(k),'_',name,'.png');
                    elseif(k>=10 && k<=99)
                        filename=strcat(name,'/','0',num2str(k),'_',name,'.png');    
                    else
                        filename=strcat(name,'/',num2str(k),'_',name,'.png');
                    end
                    strcat(filepath,'/',name,'/',filename);
                    
                    imwrite(Image,filename,'png');
                        
                    [C,~] = semanticseg(Image, net);
                    pixelArray(k) = sum(C(:) == "Embryo")*pixel2real;
                    Image = labeloverlay(Image, C);
                    if(k<10)
                        filename=strcat(name,'_new','/','00',num2str(k),'_',name,'.png');
                    elseif(k>=10 && k<=99)
                        filename=strcat(name,'_new','/','0',num2str(k),'_',name,'.png');    
                    else
                        filename=strcat(name,'_new','/',num2str(k),'_',name,'.png');
                    end
                    strcat(filepath,'_new','/',name,'/',filename);
                    
                    imwrite(Image,filename,'png');
                end
            
                baseTime = timeArray(1);
                for k=1:numFrames
                   timeArray(k) = timeArray(k) - baseTime; 
                end
                
                rankSize(i,2) = pixelArray(numFrames);
                app.storePixels{i} = pixelArray;
                P = linearRegression(pixelArray, timeArray);
                app.storeTime{i} = timeArray;
                app.storeRegress = [app.storeRegress; P];
                rankGrowth(i,2) = P(1);
                name = convertCharsToStrings(name);
                array = [name timeArray(numFrames) pixelArray(1) pixelArray(numFrames) 1 rankGrowth(i,2) 1];
                output = [output; array];
            end

            cd(app.current);
            
            if(numVideos ~= 1)
                for i=2:numVideos
                    key = rankSize(i, 2);
                    m = i - 1;
                
                    while(m >= 1 && rankSize(m, 2) > key)
                        rankSize(m+1, 2) = rankSize(m, 2);
                        rankSize(m+1, 1) = rankSize(m, 1);
                        m = m - 1;
                    end
                    rankSize(m+1, 2) = key;
                    rankSize(m+1, 1) = i;
                end
                for i=2:numVideos
                    key = rankGrowth(i, 2);
                    m = i - 1;
                
                    while(m >= 1 && rankGrowth(m, 2) > key)
                        rankGrowth(m+1, 2) = rankGrowth(m, 2);
                        rankGrowth(m+1, 1) = rankGrowth(m, 1);
                        m = m - 1;
                    end
                    rankGrowth(m+1, 2) = key;
                    rankGrowth(m+1, 1) = i;
                end
                for i=1:numVideos
                    output(1+rankSize(i), 5) = numVideos + 1 - i;
                    output(1+rankGrowth(i), 7) = numVideos + 1 - i;
                end
            end
            for i=1:numVideos
                app.storeFinalSizeRank = [app.storeFinalSizeRank; output(1+i, 1) output(1+i, 5) output(1+i,4)];
                app.storeAGRRank = [app.storeAGRRank; output(1+i, 1) output(1+i, 7) output(1+i,6)];
            end
            app.finalResult = output;
            app.PleasewaitLabel.Text = 'Predictions Done...';
            drawnow
        end
    end
    
    methods (Access = public)
        
        function sortall(app)
            numVideos = size(app.finalResult(:,1));
            for i=1:numVideos(1)-1
                for j=1:numVideos(1)-1
                    if(str2double(app.storeFinalSizeRank(i,2)) == j)
                        temp = app.storeFinalSizeRank(j,:);
                        app.storeFinalSizeRank(j,:) = app.storeFinalSizeRank(i,:);
                        app.storeFinalSizeRank(i,:) = temp;
                    end
                    if(str2double(app.storeAGRRank(i,2)) == j)
                        temp = app.storeAGRRank(j,:);
                        app.storeAGRRank(j,:) = app.storeAGRRank(i,:);
                        app.storeAGRRank(i,:) = temp;
                    end
                end
            end
            for k=1:numVideos(1)-1
                video.name = app.finalResult(k+1,1);
                for m=1:numVideos(1)-1
                    if(video.name == app.storeFinalSizeRank(m,1))
                        video.sizeRank = app.storeFinalSizeRank(m,2);
                        video.size = app.storeFinalSizeRank(m,3);
                    end
                    if(video.name == app.storeAGRRank(m,1))
                        video.growthRank = app.storeAGRRank(m,2);
                    end
                end
                video.pixel = app.storePixels{k};
                video.regress = app.storeRegress(k,:);
                video.time = app.storeTime{k};
                app.storeAll = [app.storeAll; video];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app2, files)
            app.file = files;
            app.current = pwd;
            delete(app2);
            output(app);
            sortall(app);
            plot(app.UIAxes,app.storeAll(1).pixel,'-r');
            hold(app.UIAxes,'on');
            %last = size(app.storeAll(1).pixel);
            %x = linspace(0,last(2),50);
            x = app.storeTime{1};
            plot(app.UIAxes,app.storeAll(1).regress(1,1)*x+app.storeAll(1).regress(1,2),'--b');
            %plot(app.UIAxes,polyval(app.storeAll(1).regress(1),x),'--b');
            hold(app.UIAxes, 'off');
            table = [app.storeAll(1).name app.storeAll(1).size app.storeAll(1).regress(1,1)];
            app.UITable2.Data = table;
            app.UITable.Data = app.storeFinalSizeRank;
            app.UITable_2.Data = app.storeAGRRank;
            app.storeGraph = 1;
            s = uistyle;
            s.BackgroundColor = 'y';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            numVideos = size(app.finalResult(:,1));
            for k=1:numVideos(1)-1
                if(app.storeFinalSizeRank(app.storeGraph,1) == app.storeAGRRank(k,1))
                    app.storeGraph2 = k;
                    addStyle(app.UITable_2, s, 'row', app.storeGraph2);
                end
            end
            app.PleasewaitLabel.Text = 'Ready.';
        end

        % Button pushed function: LoadnewvideosButton
        function LoadnewvideosButtonPushed(app, event)
            if(app.saveImage ~= 1)
                rmdir('Images & Labels', 's');
            end
            index;
            delete(app);
        end

        % Button pushed function: SaveresultsButton
        function SaveresultsButtonPushed(app, event)
            path = uigetdir;
            cd(path);
            writematrix(app.finalResult, 'embryo_results.csv');
            cd(app.current);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            row = event.Indices(1);
            
            s = uistyle;
            s.BackgroundColor = 'w';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            
            app.storeGraph = row;
            s.BackgroundColor = 'y';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            
            numVideos = size(app.finalResult(:,1));
            for k=1:numVideos(1)-1
                if(app.storeFinalSizeRank(row,1) == app.storeAll(k).name)
                    plot(app.UIAxes,app.storeAll(k).pixel,'-r');
                    hold(app.UIAxes,'on');
                    %last = size(app.storeAll(k).pixel);
                    %x = linspace(0,last(2),50);
                    x = app.storeAll(k).time;
                    plot(app.UIAxes,app.storeAll(k).regress(1,1)*x+app.storeAll(k).regress(1,2),'--b');
                    hold(app.UIAxes, 'off');
                    app.storeGraph2 = str2double(app.storeAll(k).growthRank);
                    addStyle(app.UITable_2, s, 'row', app.storeGraph2);
                    table = [app.storeAll(k).name app.storeAll(k).size app.storeAll(k).regress(1,1)];
                    app.UITable2.Data = table;
                end
            end
        end

        % Cell selection callback: UITable_2
        function UITable_2CellSelection(app, event)
            row = event.Indices(1);
            
            s = uistyle;
            s.BackgroundColor = 'w';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            
            app.storeGraph2 = row;
            s.BackgroundColor = 'y';
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            
            numVideos = size(app.finalResult(:,1));
            for k=1:numVideos(1)-1
                if(app.storeAGRRank(row,1) == app.storeAll(k).name)
                    plot(app.UIAxes,app.storeAll(k).pixel,'-r');
                    hold(app.UIAxes,'on');
                    %last = size(app.storeAll(k).pixel);
                    %x = linspace(0,last(2),50);
                    x = app.storeAll(k).time;
                    plot(app.UIAxes,app.storeAll(k).regress(1,1)*x+app.storeAll(k).regress(1,2),'--b');
                    hold(app.UIAxes, 'off');
                    app.storeGraph = str2double(app.storeAll(k).sizeRank);
                    addStyle(app.UITable, s, 'row', app.storeGraph);
                    table = [app.storeAll(k).name app.storeAll(k).size app.storeAll(k).regress(1,1)];
                    app.UITable2.Data = table;
                end
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            s = uistyle;
            s.BackgroundColor = 'w';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            
            numVideos = size(app.finalResult(:,1));
            app.storeGraph = app.storeGraph + 1;
            if(app.storeGraph > numVideos(1)-1)
                app.storeGraph = 1;
            end
            
            s.BackgroundColor = 'y';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            
            for k=1:numVideos(1)-1
                if(app.storeFinalSizeRank(app.storeGraph,1) == app.storeAll(k).name)
                    plot(app.UIAxes,app.storeAll(k).pixel,'-r');
                    hold(app.UIAxes,'on');
                    %last = size(app.storeAll(k).pixel);
                    %x = linspace(0,last(2),50);
                    x = app.storeAll(k).time;
                    plot(app.UIAxes,app.storeAll(k).regress(1,1)*x+app.storeAll(k).regress(1,2),'--b');
                    hold(app.UIAxes, 'off');
                    app.storeGraph2 = str2double(app.storeAll(k).growthRank);
                    addStyle(app.UITable_2, s, 'row', app.storeGraph2);
                    table = [app.storeAll(k).name app.storeAll(k).size app.storeAll(k).regress(1,1)];
                    app.UITable2.Data = table;
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            s = uistyle;
            s.BackgroundColor = 'w';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            addStyle(app.UITable_2, s, 'row', app.storeGraph2);
            
            numVideos = size(app.finalResult(:,1));
            app.storeGraph = app.storeGraph - 1;
            if(app.storeGraph < 1)
                app.storeGraph = numVideos(1)-1;
            end            

            s.BackgroundColor = 'y';
            addStyle(app.UITable, s, 'row', app.storeGraph);
            
            numVideos = size(app.finalResult(:,1));
            for k=1:numVideos(1)-1
                if(app.storeFinalSizeRank(app.storeGraph,1) == app.storeAll(k).name)
                    plot(app.UIAxes,app.storeAll(k).pixel,'-r');
                    hold(app.UIAxes,'on');
                    %last = size(app.storeAll(k).pixel);
                    %x = linspace(0,last(2),50);
                    x = app.storeAll(k).time;
                    plot(app.UIAxes,app.storeAll(k).regress(1,1)*x+app.storeAll(k).regress(1,2),'--b');
                    hold(app.UIAxes, 'off');
                    app.storeGraph2 = str2double(app.storeAll(k).growthRank);
                    addStyle(app.UITable_2, s, 'row', app.storeGraph2);
                    table = [app.storeAll(k).name app.storeAll(k).size app.storeAll(k).regress(1,1)];
                    app.UITable2.Data = table;
                end
            end
        end

        % Callback function
        function SaveimageslabelsButtonPushed(app, event)
        
        end

        % Button pushed function: SaveImagesLabelsButton
        function SaveImagesLabelsButtonPushed(app, event)
            app.saveImage = 1;
            imagePath = uigetdir;
            movefile('Images & Labels',imagePath,'f');
        end

        % Callback function
        function NoButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Embryo Growth Over Time')
            xlabel(app.UIAxes, 'Time (h)')
            ylabel(app.UIAxes, 'Embryo Size (um^2)')
            app.PleasewaitLabel = uilabel(app.UIFigure);
            app.PleasewaitLabel.Text = 'Please wait...';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Video'; 'Rank'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Video'; 'Rank'};
            app.UITable_2.RowName = {};
            app.UITable_2.CellSelectionCallback = createCallbackFcn(app, @UITable_2CellSelection, true);
            app.FinalEmbryoSizeLabel = uilabel(app.UIFigure);
            app.FinalEmbryoSizeLabel.Text = 'Final Embryo Size';
            app.AverageGrowthRateLabel = uilabel(app.UIFigure);
            app.AverageGrowthRateLabel.Text = 'Average Growth Rate';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '<';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Video'; 'Final Size'; 'Average Growth Rate'};
            app.UITable2.RowName = {};
            app.Panel = uipanel(app.UIFigure);
            app.SaveresultsButton = uibutton(app.Panel, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveresultsButtonPushed, true);
            app.SaveresultsButton.Text = 'Save results';
            app.LoadnewvideosButton = uibutton(app.Panel, 'push');
            app.LoadnewvideosButton.ButtonPushedFcn = createCallbackFcn(app, @LoadnewvideosButtonPushed, true);
            app.LoadnewvideosButton.Text = 'Load new videos';
            app.SaveImagesLabelsButton = uibutton(app.Panel, 'push');
            app.SaveImagesLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImagesLabelsButtonPushed, true);
            app.SaveImagesLabelsButton.Text = 'Save Images & Labels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualizeResults(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end